classdef Hodograph_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Plot_3DTab                    matlab.ui.container.Tab
        Plot_3DButton                 matlab.ui.control.Button
        plot_3D                       matlab.ui.control.UIAxes
        min_3DSliderLabel             matlab.ui.control.Label
        min_3DSlider                  matlab.ui.control.Slider
        max_3DSliderLabel             matlab.ui.control.Label
        max_3DSlider                  matlab.ui.control.Slider
        Data_FileEditFieldLabel       matlab.ui.control.Label
        Data_FileEditField            matlab.ui.control.EditField
        Hodograph_RunTab              matlab.ui.container.Tab
        StartButton                   matlab.ui.control.Button
        Next_FileButton               matlab.ui.control.Button
        Save_DataButton               matlab.ui.control.Button
        Min_AltLabel                  matlab.ui.control.Label
        min_alt                       matlab.ui.control.Slider
        Max_AltLabel                  matlab.ui.control.Label
        max_alt                       matlab.ui.control.Slider
        Data_DirectoryEditFieldLabel  matlab.ui.control.Label
        Data_DirectoryEditField       matlab.ui.control.EditField
        UIAxes5                       matlab.ui.control.UIAxes
        Current_FileEditField_2Label  matlab.ui.control.Label
        Current_FileEditField         matlab.ui.control.EditField
        MinALTEditFieldLabel          matlab.ui.control.Label
        MinALTEditField               matlab.ui.control.EditField
        MaxALTEditFieldLabel          matlab.ui.control.Label
        MaxALTEditField               matlab.ui.control.EditField
        WarningsEditFieldLabel        matlab.ui.control.Label
        WarningsEditField             matlab.ui.control.EditField
        Save_Data_DIrectoryEditFieldLabel  matlab.ui.control.Label
        Save_Data_DIrectoryEditField  matlab.ui.control.EditField
        Main_DirectoryEditFieldLabel  matlab.ui.control.Label
        Main_DirectoryEditField       matlab.ui.control.EditField
        Plot_HodographTab             matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        PlotButton                    matlab.ui.control.Button
        Hodograph_DataEditFieldLabel  matlab.ui.control.Label
        Hodograph_DataEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        temp;
        data;
        Alt;
        u;
        v;
        bvFreqSquared;
        upper;
        lower;
        i = 1;
        wl = 12000;
        wu = 33000;
        filesp
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Plot_3DButton
        function Plot_3DButtonPushed(app, event)
            datal = readDFM17(convertCharsToStrings(app.Data_FileEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            
            il = app.i;
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.filesp = files;
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
            end
            RS = mean(app.data.Rs);
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
%             app.lower = find(app.data.Alt>=app.wl,1,'first');
%             app.upper = find(app.data.Alt>=app.wu,1,'first');
%             plot(app.UIAxes5 ,app.u(app.lower:app.upper),app.v(app.lower:app.upper),'b')
        end

        % Value changed function: min_alt
        function min_altValueChanged(app, event)
            value = app.min_alt.Value;
            app.wl = value;
            wll = app.wl;
            wul = app.max_alt.Value;
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            app.MinALTEditField.Value = num2str(wll);
        end

        % Value changed function: max_alt
        function max_altValueChanged(app, event)
            value = app.max_alt.Value;
            app.wu = value;
            wll = app.min_alt.Value;
            wul = app.wu;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            app.MaxALTEditField.Value = num2str(wul);
        end

        % Button pushed function: Next_FileButton
        function Next_FileButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.i = app.i+1;
            il = app.i;
            if app.i > size(files)
                app.WarningsEditField.Value = 'END of flight data';
            end
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
                app.i = app.i+1;
            end
            RS = mean(app.data.Rs);
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
%             app.lower = find(app.data.Alt>=app.wl,1,'first');
%             app.upper = find(app.data.Alt>=app.wu,1,'first');
%             plot(app.UIAxes5 ,app.u(app.lower:app.upper),app.v(app.lower:app.upper),'b')
        end

        % Button pushed function: Save_DataButton
        function Save_DataButtonPushed(app, event)
            fprintf("%d, %d\n", app.Alt(app.lower), app.Alt(app.upper));
            T = table(app.Alt(app.lower:app.upper), app.u(app.lower:app.upper), app.v(app.lower:app.upper), app.temp(app.lower:app.upper), app.bvFreqSquared(app.lower:app.upper));
            T.Properties.VariableNames = {'Alt' 'u' 'v' 'temp' 'bv2'};
            [~, n, ~] = fileparts(app.filesp(app.i).name);
            fname = sprintf("%s\\%03u_%s-%d-%d.txt",append(app.Main_DirectoryEditField.Value,app.Save_Data_DIrectoryEditField.Value),app.i, n, app.Alt(app.lower), app.Alt(app.upper));
            writetable(T, fname);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            d =  append(app.Main_DirectoryEditField.Value,app.Hodograph_DataEditField.Value);

            t = fullfile(d, "*.txt");
            files = dir(t); 
            axs = [];
            series_figure = figure;
            hodo_fig = figure; offset = 10;
            arrow_offset = 1 / 15;
            counter = 1;
            already_seen = [];
            flight_num = [];
            offsets = [];
            coriolisFreq = coriolisFrequency(30.25);
            unique_flights = strings(1,length(files));
            for i = 1:length(files)
                a = sprintf('%02u',i);
                h = append('F',a);
                unique_flights(i) = h;
            end
            for k=1:size(unique_flights, 2)
                for i=1:size(files)
                    flight_number = files(i).name(1:3);
                    flight_number(1) = 'F';
                if strcmp(flight_number, unique_flights(k)) == 0
                    %fprintf("%s %s\n", unique_flights(k), flight_number);
                    continue;
                end
                % if str2num(flight_number(2:end)) ~= 23
                %     continue;
                % end
                data = readtable(fullfile(d, files(i).name));
                set(0, 'CurrentFigure', hodo_fig);
                ax = subplot(6, 5, i);
                axis equal; % this is needed to keep plots circular
                axs = [axs;ax];
                eps = fit_ellipse(data.u, data.v, ax);
                hold on
                plot(data.u, data.v, 'k.')
                lambda_z = 2*(data.Alt(end) - data.Alt(1));
                m = 2*pi / lambda_z;
                p = eps.phi;
                rot = [cos(p) -sin(p); sin(p) cos(p)];
                uv = [data.u data.v];
                uvrot = rot*uv';
                urot = uvrot(1, :);
                dT = data.temp(2:end) - data.temp(1:end-1);
                dz = data.Alt(2:end) - data.Alt(1:end-1);
                wf = eps.long_axis / eps.short_axis;
                bvMean = mean(data.bv2);
                intrinsicFreq = coriolisFreq*wf;
                %fprintf("%0.10f, %0.10f ", (coriolisFreq^2 * m^2)/abs(bvMean), wf^2 - 1);
                k_h = sqrt((coriolisFreq^2*m^2)/(abs(bvMean))*(wf^2 - 1)); % horizontal wavenumber (1 / meters)
                %fprintf("%f %f\n", intrinsicFreq, wf);
                intrinsicHorizPhaseSpeed = intrinsicFreq / k_h; % m/s
                fprintf("m:%f, lz:%f, h:%f,, bv:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, bvMean);
                k_h_2 = sqrt((intrinsicFreq^2 - coriolisFreq^2 )* (m^2 / abs(bvMean)));
                int2 = intrinsicFreq/k_h_2;
                % fprintf("m:%f, lz:%f, h:%f, kh:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, int2);
                dTdz = dT./dz;
                eta = mean(dTdz.*urot(1:end-1));
                if eta < 0
                    p = p - pi;
                end
                str = sprintf("f: %s, ^{w}/{f}=%0.1f, t=%0.1f, m_z=%0.1f", ...
                    files(i).name(1:3), wf, azimuthFromUnitCircle(rad2deg(p)),...
                    lambda_z/1000);
                %title(str);
                fprintf("%s\n", str);
                set(0, 'CurrentFigure', series_figure);
                xlim([5, 155]);
                ylim([12, 33]);
                Alt_of_detection_km = mean(data.Alt) / 1000;
                x1 = counter*offset;
                x2 = counter*offset + wf*cosd(rad2deg(p));
                y1 = Alt_of_detection_km;
                y2 = Alt_of_detection_km + wf*sind(rad2deg(p));
                hold on;
                p1 = [x1 y1];
                p2 = [x2 y2];
                dp = p2-p1;
                %quiver(p1(1),p1(2),dp(1),dp(2), 'AutoScale', 'on', 'color','#9a0200', 'linewidth', 2);
                [xf, yf] = ds2nfu([x1 x2], [y1 y2]);
                annotation(gcf, 'arrow', xf,yf, 'color', '#9a0200', 'LineWidth', 2) %plot arrow angle of propagation length corresponds to magnitude of intrinsic frequency
                if strcmp(flight_number, "F23")
                    if Alt_of_detection_km > 20 && Alt_of_detection_km < 24
                        xc = [xf(1) - 0.125, xf(2)-0.02];
                        yc = [yf(1) + 0.1, yf(1)+0.05];
                        annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #2')
                    elseif Alt_of_detection_km > 20
                        xc = [xf(1) - 0.125, xf(2)- 0.02];
                        yc = [yf(1) + 0.1, yf(1)+0.05];
                        annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #3')
                    end
                    fprintf("%f\n", Alt_of_detection_km);
                end
                %plot([x1 x2], [y1 y2], 'color', '#9a0200', 'linewidth', 3);
                placeholder = [counter*offset counter*offset];
                plot(placeholder, [12, 32.5], 'k'); % plot Altitude in km.
                end
                offsets = [offsets, counter*offset];
                counter = counter + 1;
            end
            % 10 -> 150
            
            linkaxes(axs, 'xy');
            set(0, 'CurrentFigure', hodo_fig);
            sgtitle("All hodographs for the radiosonde campaign")
            set(0, 'CurrentFigure', series_figure);
            % %offsets = offsets(1:end-1);
            flight_num = flight_num(1:end-1, :);
            xticks(offsets);
            xticklabels(unique_flights); 
            set(gca,'XTickLabelRotation', 45, 'fontsize', 16)
            ylabel("Altitude of detection (km)") 
            xlabel("Flight number")
            title("Propagation direction and detection Altitude of gravity waves (hodograph method)", 'fontsize', 16);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Plot_3DTab = uitab(app.TabGroup);
            app.Plot_3DTab.Title = 'Plot_3D';
            app.Plot_3DButton = uibutton(app.Plot_3DTab, 'push');
            app.Plot_3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_3DButtonPushed, true);
            app.Plot_3DButton.Text = 'Plot_3D';
            app.plot_3D = uiaxes(app.Plot_3DTab);
            title(app.plot_3D, 'Title')
            xlabel(app.plot_3D, 'X')
            ylabel(app.plot_3D, 'Y')
            app.min_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.min_3DSliderLabel.Text = 'min_3D';
            app.min_3DSlider = uislider(app.Plot_3DTab);
            app.min_3DSlider.Limits = [10000 33000];
            app.min_3DSlider.Value = 10000;
            app.max_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.max_3DSliderLabel.Text = 'max_3D';
            app.max_3DSlider = uislider(app.Plot_3DTab);
            app.max_3DSlider.Limits = [10000 33000];
            app.max_3DSlider.Value = 33000;
            app.Data_FileEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Data_FileEditFieldLabel.Text = 'Data_File';
            app.Data_FileEditField = uieditfield(app.Plot_3DTab, 'text');
            app.Hodograph_RunTab = uitab(app.TabGroup);
            app.Hodograph_RunTab.Title = 'Hodograph_Run';
            app.StartButton = uibutton(app.Hodograph_RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Next_FileButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Next_FileButton.ButtonPushedFcn = createCallbackFcn(app, @Next_FileButtonPushed, true);
            app.Next_FileButton.Text = 'Next_File';
            app.Save_DataButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Save_DataButton.ButtonPushedFcn = createCallbackFcn(app, @Save_DataButtonPushed, true);
            app.Save_DataButton.Text = 'Save_Data';
            app.Min_AltLabel = uilabel(app.Hodograph_RunTab);
            app.Min_AltLabel.Text = 'Min_Alt';
            app.min_alt = uislider(app.Hodograph_RunTab);
            app.min_alt.Limits = [12000 33000];
            app.min_alt.ValueChangedFcn = createCallbackFcn(app, @min_altValueChanged, true);
            app.min_alt.Value = 12000;
            app.Max_AltLabel = uilabel(app.Hodograph_RunTab);
            app.Max_AltLabel.Text = 'Max_Alt';
            app.max_alt = uislider(app.Hodograph_RunTab);
            app.max_alt.Limits = [12000 33000];
            app.max_alt.ValueChangedFcn = createCallbackFcn(app, @max_altValueChanged, true);
            app.max_alt.Value = 33000;
            app.Data_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Data_DirectoryEditFieldLabel.Text = 'Data_Directory';
            app.Data_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Data_DirectoryEditField.Value = '.\eclipseData';
            app.UIAxes5 = uiaxes(app.Hodograph_RunTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Current_FileEditField_2Label = uilabel(app.Hodograph_RunTab);
            app.Current_FileEditField_2Label.Text = 'Current_File';
            app.Current_FileEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.MinALTEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.MinALTEditFieldLabel.Text = 'MinALT';
            app.MinALTEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.MaxALTEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.MaxALTEditFieldLabel.Text = 'MaxALT';
            app.MaxALTEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.WarningsEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.WarningsEditFieldLabel.Text = 'Warnings';
            app.WarningsEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Save_Data_DIrectoryEditFieldLabel.Text = 'Save_Data_DIrectory';
            app.Save_Data_DIrectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditField.Value = '.\\hodograph';
            app.Main_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Main_DirectoryEditFieldLabel.Text = 'Main_Directory';
            app.Main_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Main_DirectoryEditField.Value = 'E:\GIT\Eclipse_Matlab\gravity-wave-gui-matlab-master\gravity-wave-gui-matlab-master';
            app.Plot_HodographTab = uitab(app.TabGroup);
            app.Plot_HodographTab.Title = 'Plot_Hodograph';
            app.GridLayout = uigridlayout(app.Plot_HodographTab);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Hodograph_DataEditFieldLabel = uilabel(app.GridLayout);
            app.Hodograph_DataEditFieldLabel.Text = 'Hodograph_Data';
            app.Hodograph_DataEditField = uieditfield(app.GridLayout, 'text');
            app.Hodograph_DataEditField.Value = '.\hodograph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hodograph_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end